classdef ACh_training_v4b2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TrialstructurePanel           matlab.ui.container.Panel
        sLabel                        matlab.ui.control.Label
        sLabel_2                      matlab.ui.control.Label
        sLabel_3                      matlab.ui.control.Label
        sLabel_4                      matlab.ui.control.Label
        PassivewTSDropDownLabel       matlab.ui.control.Label
        PassivewTSDropDown            matlab.ui.control.DropDown
        RewardEditFieldLabel          matlab.ui.control.Label
        RewardEditField               matlab.ui.control.NumericEditField
        StimdurationEditFieldLabel    matlab.ui.control.Label
        StimdurationEditField         matlab.ui.control.NumericEditField
        RespwinEditFieldLabel         matlab.ui.control.Label
        RespwinEditField              matlab.ui.control.NumericEditField
        ITIEditFieldLabel             matlab.ui.control.Label
        ITI1                          matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        ITI2                          matlab.ui.control.NumericEditField
        SetButton                     matlab.ui.control.Button
        NLDSDropDownLabel             matlab.ui.control.Label
        NLDSDropDown                  matlab.ui.control.DropDown
        CommunicationPanel            matlab.ui.container.Panel
        comm_Lick_lamp                matlab.ui.control.Lamp
        comm_Lick_connect             matlab.ui.control.Button
        LickEditFieldLabel            matlab.ui.control.Label
        comm_Lick_com                 matlab.ui.control.EditField
        comm_2pPC_lamp                matlab.ui.control.Lamp
        comm_2pPC_connect             matlab.ui.control.Button
        pPCEditFieldLabel             matlab.ui.control.Label
        comm_2pPC_com                 matlab.ui.control.EditField
        comm_Motion_lamp              matlab.ui.control.Lamp
        comm_Motion_connect           matlab.ui.control.Button
        MotionEditFieldLabel          matlab.ui.control.Label
        comm_Motion_com               matlab.ui.control.EditField
        LogPanel                      matlab.ui.container.Panel
        SubjectTextAreaLabel          matlab.ui.control.Label
        SubjectTextArea               matlab.ui.control.TextArea
        DateTextAreaLabel             matlab.ui.control.Label
        DateTextArea                  matlab.ui.control.TextArea
        SavepathTextAreaLabel         matlab.ui.control.Label
        SavepathTextArea              matlab.ui.control.TextArea
        BrowseButton                  matlab.ui.control.Button
        SessionEditFieldLabel         matlab.ui.control.Label
        SessionEditField              matlab.ui.control.NumericEditField
        VisualPanel                   matlab.ui.container.Panel
        GoEditFieldLabel              matlab.ui.control.Label
        GoEditField                   matlab.ui.control.NumericEditField
        degLabel                      matlab.ui.control.Label
        degLabel_2                    matlab.ui.control.Label
        NogoEditFieldLabel            matlab.ui.control.Label
        NogoEditField                 matlab.ui.control.NumericEditField
        CPDEditFieldLabel             matlab.ui.control.Label
        CPDEditField                  matlab.ui.control.NumericEditField
        CPSEditFieldLabel             matlab.ui.control.Label
        CPSEditField                  matlab.ui.control.NumericEditField
        ApSizeEditFieldLabel          matlab.ui.control.Label
        ApSizeEditField               matlab.ui.control.NumericEditField
        ApLocXEditFieldLabel          matlab.ui.control.Label
        ApLocXEditField               matlab.ui.control.NumericEditField
        DegToPxEditFieldLabel         matlab.ui.control.Label
        DegToPxEditField              matlab.ui.control.NumericEditField
        ApLocYEditFieldLabel          matlab.ui.control.Label
        ApLocYEditField               matlab.ui.control.NumericEditField
        degLabel_3                    matlab.ui.control.Label
        ExecutioncontrolPanel         matlab.ui.container.Panel
        StartButton                   matlab.ui.control.Button
        NumberoftrialsLabel           matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        Text_NumTrl                   matlab.ui.control.TextArea
        CurrentBLEditField            matlab.ui.control.NumericEditField
        GetBLButton                   matlab.ui.control.Button
        SetButton_2                   matlab.ui.control.Button
        ThInput                       matlab.ui.control.NumericEditField
        NolickEditFieldLabel_2        matlab.ui.control.Label
        Trialcounterupdatedelay1trialLabel  matlab.ui.control.Label
        InterjectButton               matlab.ui.control.Button
        InterjectofpassivesEditFieldLabel  matlab.ui.control.Label
        InterjectofpassivesEditField  matlab.ui.control.NumericEditField
        LickLampLabel                 matlab.ui.control.Label
        LickLamp                      matlab.ui.control.Lamp
        MotionLampLabel               matlab.ui.control.Label
        MotionLamp                    matlab.ui.control.Lamp
        incpasLabel                   matlab.ui.control.Label
        Text_NumPas                   matlab.ui.control.TextArea
        excabtLabel                   matlab.ui.control.Label
        Text_NumAbt                   matlab.ui.control.TextArea
        Performanceinstantaneouspast20trialsPanel  matlab.ui.container.Panel
        HLabel_2                      matlab.ui.control.Label
        Text_NumHit_i                 matlab.ui.control.TextArea
        CRLabel_2                     matlab.ui.control.Label
        Text_NumCR_i                  matlab.ui.control.TextArea
        MLabel_2                      matlab.ui.control.Label
        Text_NumMiss_i                matlab.ui.control.TextArea
        FALabel_2                     matlab.ui.control.Label
        Text_NumFA_i                  matlab.ui.control.TextArea
        HRLabel                       matlab.ui.control.Label
        Text_NumHR_i                  matlab.ui.control.TextArea
        FARLabel                      matlab.ui.control.Label
        Text_NumFAR_i                 matlab.ui.control.TextArea
        idLabel                       matlab.ui.control.Label
        Text_NumDprime_i              matlab.ui.control.TextArea
        PerformancecumulativePanel    matlab.ui.container.Panel
        HLabel_3                      matlab.ui.control.Label
        Text_NumHit_c                 matlab.ui.control.TextArea
        cdLabel                       matlab.ui.control.Label
        Text_NumDprime_c              matlab.ui.control.TextArea
        HRLabel_2                     matlab.ui.control.Label
        Text_NumHR_c                  matlab.ui.control.TextArea
        FARLabel_2                    matlab.ui.control.Label
        Text_NumFAR_c                 matlab.ui.control.TextArea
        CRLabel_3                     matlab.ui.control.Label
        Text_NumCR_c                  matlab.ui.control.TextArea
        MLabel_3                      matlab.ui.control.Label
        Text_NumMiss_c                matlab.ui.control.TextArea
        FALabel_3                     matlab.ui.control.Label
        Text_NumFA_c                  matlab.ui.control.TextArea
        SetButton_3                   matlab.ui.control.Button
        ReadyLampLabel                matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        NLDSFAPanel                   matlab.ui.container.Panel
        NLDSendrelonEditFieldLabel    matlab.ui.control.Label
        NLDSendrelonEditField         matlab.ui.control.NumericEditField
        sLabel_5                      matlab.ui.control.Label
        FAtimeoutdurEditFieldLabel    matlab.ui.control.Label
        FAtimeoutdurEditField         matlab.ui.control.NumericEditField
        sLabel_6                      matlab.ui.control.Label
        NLDStimeoutdurEditFieldLabel  matlab.ui.control.Label
        NLDStimeoutdurEditField       matlab.ui.control.NumericEditField
        sLabel_7                      matlab.ui.control.Label
        LicksoundDropDownLabel        matlab.ui.control.Label
        LicksoundDropDown             matlab.ui.control.DropDown
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        function startSB(app)
            global Par
            
            if Par.g2p == 1
                if ~isempty(Par.Lcon) && strcmp(get(Par.Lcon, 'Status'), 'closed')
                    fopen(Par.Lcon);
                end
                fprintf(Par.Lcon,['A' Par.dbfields.subject]); % set animal id field
                fprintf(Par.Lcon,['U' Par.dbfields.date]); % set date
                fprintf(Par.Lcon,['E ' sprintf('%03d', app.SessionEditField.Value)]); % set session num
                fprintf(Par.Lcon,'G'); %start grabbing
                pause(10); % short pause to let the scope initializes
            else
                disp('Running trials without 2P!')
            end
        end
        
        function showDG(app, ori)
            global Par lds step t_punBegin data trl n_aborted t_trl_abs nlds_timeout rt_aborted
            global texDG dstRect
            
            phase = 0;
            freq = Par.CPD / Par.DegToPx; % convert CPD into pixel
            contrast = 1;
            num_cycles = Par.StimDur * Par.CPS;
            d_phase = (num_cycles * 360)/(60*Par.StimDur);
            firstflip_flag = 1;
            ori = ori + 90; % correct for PTB drift direction
            lds = 0;
            nlds_timeout = 0;
            t_start = GetSecs;
            
            
            while GetSecs < t_start + Par.StimDur
                
                  phase = phase + d_phase;
                  Screen('DrawTexture', Par.PTB.window, texDG, [], dstRect, ori, [], [], [], [], [], [phase, freq, contrast, 0]);
                  Screen('Flip', Par.PTB.window);
                  
                  if firstflip_flag == 1 && Par.das == 1 % DAS bit after 1st flip
                      dasbit(0,1);
                        if ~isempty(Par.Lcon)% send trial ID to SB
                            fprintf(Par.Lcon,['M' num2str(ori-90)]); % ori-90 here to go back from the PTB drift coordinate frame back to original 
                        end 
                      firstflip_flag = 0;
                  elseif firstflip_flag == 1 && Par.das == 0
                      firstflip_flag = 0;
                  end
                  
                  licksen_check(app, Par.NLDS);
                  
                  if lds == 1 && GetSecs < t_start + Par.EndRelToOnset_NLDS
                      break
                  end
            end
            
            if Par.das == 1 % DAS bit low after LAST flip
                dasbit(0,0)
            end
            
            if lds == 1 && GetSecs < t_start + Par.EndRelToOnset_NLDS % if animal licked during stimulus AND par.NLDS is set to true
                show_black(app);
                fprintf(licksen, 'ID'); % disable AD
                step = 777;
                t_punBegin = tic;
                disp('Aborted! NLDS timeout');
                data(trl,:) = []; % remove this aborted trial in the data log 
                t_trl_abs(:,end) = []; % remvoe this aborted trial from the log of AD-reported trial time
                trl = trl - 1; 
                n_aborted = n_aborted + 1;
                nlds_timeout = 1;
                rt_aborted = [rt_aborted; GetSecs-t_start];
            else
                show_gray(app); 
            end
            
        end
        
        function showDG_passinterj(app, ori) % this execute a passive without NLDS
            global Par
            global texDG dstRect
            
            phase = 0;
            freq = Par.CPD / Par.DegToPx; % convert CPD into pixel
            contrast = 1;
            num_cycles = Par.StimDur * Par.CPS;
            d_phase = (num_cycles * 360)/(60*Par.StimDur);
            firstflip_flag = 1;
            ori = ori + 90; % correct for PTB drift direction
            t_start = GetSecs;
            
            
            while GetSecs < t_start + Par.StimDur
                
                  phase = phase + d_phase;
                  Screen('DrawTexture', Par.PTB.window, texDG, [], dstRect, ori, [], [], [], [], [], [phase, freq, contrast, 0]);
                  Screen('Flip', Par.PTB.window);
                  
                  if firstflip_flag == 1 && Par.das == 1 % DAS bit after 1st flip
                      dasbit(0,1);
                        if ~isempty(Par.Lcon)% send trial ID to SB
                            fprintf(Par.Lcon,['M' num2str(ori-90)]); % ori-90 here to go back from the PTB drift coordinate frame back to original 
                        end 
                      firstflip_flag = 0;
                  elseif firstflip_flag == 1 && Par.das == 0
                      firstflip_flag = 0;
                  end
                  
                  licksen_check(app, Par.NLDS);
            end
            
            if Par.das == 1 % DAS bit low after LAST flip
                dasbit(0,0)
            end
            
            show_gray(app);
        end
       
        function show_gray(app)
            global Par
            Screen('FillRect', Par.PTB.window,[0/256 128/256 128/256], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
        end
      
         function show_black(app)
            global Par
            Screen('FillRect', Par.PTB.window,[0 0 0], Par.PTB.windowRect);
            Screen('Flip', Par.PTB.window, 0);
         end
         
         function licksen_check(app,nlds)
            global t_lick_abs m_licklamp licksen trl data t_trl_abs lds Par sound
            while licksen.BytesAvailable
                StrOut = fscanf(licksen);
                if strncmp('L', StrOut, 1)
                    sNr_lick = StrOut(2);
                    % t_lick_abs(end+1) = toc(t_abs0);
                    t_lick_abs(end+1) = str2num(fscanf(licksen)); % Take the AD reported time
                    app.LickLamp.Color = [0 1 0];
                    m_licklamp = tic;
                    if Par.lickSound == 1
                      PsychPortAudio('Start', sound.pahandle, sound.repetitions, 0, 0);
                    end
                    if nlds == 1
                        lds = 1;
                    end
                elseif strncmp('Reward', StrOut, 6)
                    data(trl,4)= 1;
                    data(trl,5)= str2num(fscanf(licksen));
%                     trash = fscanf(licksen);
                    disp('Hit')
                elseif strncmp('Error', StrOut, 5)
                    data(trl,4)= 0;
                    data(trl,5)= str2num(fscanf(licksen));
                    % PsychPortAudio('Start', sound.pahandle, ceil(Par.NoiseDur_FA/Par.NoiseDur_NLDS), 0, 0); % Repeating n times of the NLDS sound duration
                    disp('False alarm!')
                elseif strncmp('Base1', StrOut, 5)
                    app.CurrentBLEditField.Value = str2double(StrOut(7:end));
                elseif strncmp('TrlT', StrOut, 4)
                    t_trl_abs(2,end) = str2num(fscanf(licksen)); % Save AD reported trial delivery time
                end
            end
         end
         
         function licklamp_reset(app)
             global m_licklamp
             % Make sure the lick lamp, if turned on, stays on for
             % at least 100 ms.
             try
                 if toc(m_licklamp) > 0.1
                     app.LickLamp.Color = [1 0 0]; % Reset the lick lamp to OFF color.
                 end
             catch ME
             end
         end
         
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Par
            openobj = instrfind;
            if ~isempty(openobj)
                fclose(openobj); % close all serial ports
            end
            Par.dbfields = getdbfields('VC');
            app.SubjectTextArea.Value = Par.dbfields.subject;
            app.DateTextArea.Value = Par.dbfields.date;
            
            %Initialize DAS
            try
                dasinit(0);
                Par.das = 1;
                dasbit(0,0);
            catch ME
                Par.das = 0;
                waitfor(msgbox('No DAS available!'));
            end
            
            % Initialize PTB
            PsychDefaultSetup(2);
            Par.PTB.screenNumber = 2;
            
            Screen('Preference', 'SkipSyncTests', 1);
            
            try 
                load('C:\MyGammaTable.mat',"gammaTable");
                Screen('LoadNormalizedGammaTable', Par.PTB.screenNumber, gammaTable*[1 1 1]);   
            catch ME
                waitfor(msgbox('Unable to performa gamma correction! Make sure MyGammaTable.mat is under C:\ and run the program again!'));
            end
            
            % Screen('OpenWindow',Par.PTB.screenNumber, 0.5);
            [Par.PTB.window, Par.PTB.windowRect] = PsychImaging('OpenWindow', Par.PTB.screenNumber, [0/256 128/256 128/256]);
            [Par.PTB.screenXpixels, Par.PTB.screenYpixels] = Screen('WindowSize', Par.PTB.window);
            [Par.PTB.sourceFactorOld, Par.PTB.destinationFactorOld, Par.PTB.colorMaskOld] = Screen('Blendfunction', Par.PTB.window, [], [], [0 1 1 1]);
            [Par.PTB.xCenter, Par.PTB.yCenter] = RectCenter(Par.PTB.windowRect);
            waitfor(msgbox('PTB initialized in no-red mode!'));
%             Screen('BlendFunction', Par.PTB.window, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);
            
            
            % Book-keeping vars
            Par.g2p = 0;
            Par.set = 0;
            Par.logsave = 0;
            Par.lickSound = 0;
            
            % Prepare the figure axes
            global fig1
            fig1 = animatedline(app.UIAxes);
            
            % LOG GUI VERSION NUMBER
            Par.GUIver = 'v4b';


        end

        % Button pushed function: comm_Lick_connect
        function comm_Lick_connectButtonPushed(app, event)
            global Par
            try
                Par.comm_Lick = serial(app.comm_Lick_com.Value, 'Baudrate', 115200);
                fopen(Par.comm_Lick);
                app.comm_Lick_lamp.Color = [0 1 0];
                Par.lick = 1;
            catch ME
                waitfor(msgbox('Error! Check port number.'));
                Par.lick = 0;
            end 
        end

        % Button pushed function: comm_Motion_connect
        function comm_Motion_connectPushed(app, event)
            global Par
            try
                Par.comm_Motion = serial(app.comm_Motion_com.Value, 'Baudrate', 9600);
                fopen(Par.comm_Motion);
                app.comm_Motion_lamp.Color = [0 1 0];
            catch ME
                waitfor(msgbox('Error! Check port number.'));
            end
        end

        % Button pushed function: comm_2pPC_connect
        function comm_2pPC_connectButtonPushed(app, event)
            global Par
            Par.Lcon = [];
            try
                % G2P = '192.87.10.153';
                G2P = resolvehost(app.comm_2pPC_com.Value,'address');
                Par.Lcon = udp(G2P, 'RemotePort', 7000);
                Par.Lhost = udp('Localhost', 'LocalPort', 4000, 'BytesAvailableFcn', @udp_cb);
                fopen(Par.Lhost);
                app.comm_2pPC_lamp.Color = [0 1 0];
                Par.g2p = 1;
            catch
                waitfor(msgbox('No UDP connection with Scanner system (G2P) possible'));
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.SavepathTextArea.Value = uigetdir;
            global Par
            Par.savepath = app.SavepathTextArea.Value;
            cd(char(Par.savepath));
            Par.logsave = 1;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global Par
            global passive
            global data
            global perfdata
            global fig1
            global time time_long
            global trl step
            global t_trl_abs t_lick_abs
            global licksen t_abs0 n_aborted nlds_timeout t_punBegin rt_aborted

            Par.run = 1;
            time = [];
            time_long = [];
            
           
            % ================ Housekeeping =====================
                       
            % Check parameters are all set
            if Par.set == 0
                waitfor(msgbox('Parameters & connections are NOT ready!'));
                error('Parameters & connections are NOT ready!')
            end
            %%
            
            % Start ScanBox
            startSB(app);
            
            % Empty sensor buffer
            licksen = Par.comm_Lick;
            while licksen.BytesAvailable
                StrOut = fscanf(licksen);
            end
            
            % Send initial pars to Audorino
            fprintf(licksen, strcat('IL',num2str(Par.RewardDur*1000))); % Set left valve reward time
            fprintf(licksen, strcat('IA',num2str(Par.RespWin*1000))); % Set response window duration
            
            % Housekeeping tasks
            trl_max = 400; % Maximum number of trl per run; used for preallocating log (var: data)
            data = zeros(trl_max,5); % [time trial_type NLDS outcome RT]
            t_lick = 0;
            step = 0; 
            passive = 0;
            t0 = tic;
            trl = 0;
            perfdata.num_trl = 0;
            perfdata.num_go_c = 0;
            perfdata.num_nogo_c = 0;
            perfdata.num_go_nogo_c = 0;
            perfdata.log = zeros(trl_max,16);
            perfdata.num_sti_nosti_c = 0;
            t_abs0 = tic;
            t_lick_abs = []; % to store all the lick time relavtive to session start time (reported by AD)
            t_trl_abs = []; % to store all trial type (row 1) AND trial time (row 2) relative to session start time (reported by AD)
            n_aborted = 0; % to store the number of aborted trials
            rt_aborted = []; % store the RTs of lick that resulted in abortion, rel. to stim onset;
            
            % Initialize the mini-block of 4
            id_m = [1 1 0 0];
            id_m = id_m(randperm(length(id_m)));
            id_i = 0;
            %%
            
           % Tell AD the start of a session to set session start time in AD
           fprintf(licksen, 'IU'); 
            
            % ================ Game =====================
                while Par.run == 1
                    m1 = tic; % marker m1; counter for one while loop. 
                    
                    % Make sure the lick lamp, if turned on, stays on for
                    % at least 100 ms.
                    licklamp_reset(app)
                          
                    % Block: Arduino I/O
                    licksen_check(app, 0); %These are licksen checks outside of stim so nlds = 0;
                    
                    
                    % ======================================= 
                    % Block: Passive session with trial structure (go with
                    % no-go + sound for FA during no-go)
                    if Par.Passive == 'Y' && step == 0
                        trl = trl + 1;
                        
                        % Making the mini-block of 4
                        if id_i == 4
                            id_m = [1 1 0 0];
                            id_m = id_m(randperm(length(id_m)));
                            id_i = 0;
                        end
                        id_i = id_i+1;
                        id_trl = id_m(id_i);
                        %%
                        
                        k = id_trl;
                        
                        t_trl_abs(1,end+1) = 100+k; % Specify trl ID in trial time record
                        % t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                        
                        if k == 1
                            disp(strcat('Trial ',num2str(trl),': Passive - GO'));
                            data(trl,:) = [0 100+k Par.NLDS 0 0];
                            fprintf(licksen, 'IS'); % trial start signal to AD to get TrlT
                            showDG(app,Par.OriGo);
                            if nlds_timeout == 0
                               tm1 = tic;
                               step = -1;
                            end
                            iti = datasample([Par.ITI1:0.25:Par.ITI2],1);
                        else
                            disp(strcat('Trial ',num2str(trl),': Passive - NOGO'));
                            data(trl,:) = [0 100+k Par.NLDS 1 0]; % Default to success/CR (1); if there's an error (FA) then this will be set to (0)
                            fprintf(licksen, 'IS'); % trial start signal to AD to get TrlT
                            showDG(app,Par.OriNogo);
                            if nlds_timeout == 0
                                fprintf(licksen, 'IW'); % to allow FA detection in passive nogo!
                                tm1 = tic;
                                step = -1.5;
                            end
                            iti = datasample([Par.ITI1:0.25:Par.ITI2],1);
                        end
                    end
                    
                    if step == -1 && toc(tm1) > 0.05 %this step is needed to add a tiny delay after offset, say 50ms
                        fprintf(licksen, 'IP'); % Deliver a passive
                        step = -2;
                    end
                    
                    if step == -1.5 && toc(tm1) > 0.05 %this step is needed to add a tiny delay after offset, say 50ms
                        fprintf(licksen, 'IZ'); % Open the sham valve
                        step = -2;
                    end
                    
                    if step == -2 && toc(tm1) > 2+iti
                        if data(trl,2) == 101
                            step = 0;
                        elseif data(trl,2) == 100 && data(trl,4) == 0 && Par.TimeoutDur_FA > 0
                            t_punBegin = tic;
                            step = -9;
                            disp('FA timeout');
                        else
                            step = 0;
                        end
                    end
                    
                    if step == -2.1 && toc(tm1) > iti
                        step = 0;
                    end
                    
                    if step == -9 && toc(t_punBegin) > Par.TimeoutDur_FA %add timeout punishment if FA
                        step = 0;
                    end
                    
                    % =======================================
                   
                    % =======================================
                    % Block: Normal session BUT passive interjection
                    if Par.Passive == 'N' && step == 0 && passive == 1
                        step = 100;
                        n = 0;
                    end
                        
                    if step == 100
                        trl = trl + 1;
                        t_trl_abs(1,end+1) = 101; % Specify trl ID in trial time record
                        % t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                        data(trl,:) = [0 101 Par.NLDS 0 0];
                        disp(strcat('Trial ',num2str(trl),': Passive - GO'));
                        fprintf(licksen, 'IS'); % trial start signal to AD to get TrlT
                        showDG_passinterj(app,Par.OriGo); % needed otherwise NLDS will come into play
                        t100 = tic;
                        iti = datasample([Par.ITI1:0.25:Par.ITI2],1);
                        n = n + 1;
                        step = 101;
                    end
                    
                    if step == 101 && toc(t100) > 0.05 %this step is needed to add a tiny delay to reward delivery after stim offset
                        fprintf(licksen, 'IP');
                        step = 102;
                    end
                                      
                    if step == 102 && toc(t100) > iti && n < app.InterjectofpassivesEditField.Value
                        step = 100;
                    elseif step == 102 && toc(t100) > iti && n == app.InterjectofpassivesEditField.Value
                        step = 0;
                        passive = 0;
                        t0 = tic;
                    end
                    % =======================================
                     
%                     % Block: No lick period
%                     if app.PassivewTSDropDown.Value == 'N' && step == 0 && passive == 0 
%                         jitter = datasample([-1:0.1:1], 1);
%                         if t_lick == 0 && toc(t0) > (app.StimdurationEditField.Value + jitter)
%                             step = 1;
%                         elseif t_lick > 0 && (toc(t0) - t_lick > (app.StimdurationEditField.Value) + jitter)
%                             step = 1;
%                         end
%                     end
%                     %%

                    % =======================================
                    % Block: ACTIVE TRIALS
                    if Par.Passive == 'N' && step == 0 && passive == 0
                        step = 1;
                    end
                    
                    if step == 1 
                        
                        % Making the mini-block of 4
                        if id_i == 4
                            id_m = [1 1 0 0];
                            id_m = id_m(randperm(length(id_m)));
                            id_i = 0;
                        end
                        id_i = id_i+1;
                        id_trl = id_m(id_i);
                        %%
                        
                    
                        if id_trl == 1 % if this is a GO trial
                            
                            trl = trl + 1;
                            
                            t_trl_abs(1,end+1) = 1; % Specify trl ID in trial time record
                            % t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                            
                            disp(strcat('Trial ',num2str(trl),': GO')); 
                            data(trl,:) = [0 1 Par.NLDS 0 0]; % Outcome defaults to Miss (0); If hit, then data(trl,4) will be set to 1.
                            fprintf(licksen, 'IE1'); % enable, to signal this is a go trial
                            fprintf(licksen, 'IS'); % trial start signal to AD to get TrlT
                            showDG(app,Par.OriGo);
                            if nlds_timeout == 0
                                fprintf(licksen, 'IW'); % activate response window
                                t_sti = tic;
                                step = 2; 
                            end
                        else % no-go trial
                            trl = trl + 1;
                            
                            t_trl_abs(1,end+1) = 0; % Specify trl ID in trial time record
                            % t_trl_abs(2,end) = toc(t_abs0); % Save trial delivery time
                            
                            disp(strcat('Trial ',num2str(trl),': NOGO'));
                            data(trl,:) = [0 0 Par.NLDS 1 0]; % Default to success/CR (1); if there's an error (FA) then this will be set to (0)
                            fprintf(licksen, 'IS'); % trial start signal to AD to get TrlT
                            showDG(app,Par.OriNogo);
                            if nlds_timeout == 0
                               fprintf(licksen, 'IW'); % activate response window
%                              fprintf(licksen, 'IZ'); % Open the sham valve for fake sound
                               t_sti = tic;
                               step = 2;
                            end
                           
                        end  
                        
                        iti = datasample([Par.ITI1:0.25:Par.ITI2],1);
                        
                    end
                                       
                    if step == 2 && toc(t_sti) > (Par.RespWin + Par.RewardDur + iti)
                        if trl == trl_max
                            s1 = 'Max iteration per run (';
                            s2 = num2str(trl_max);
                            s3 = ') has reached. We will now stop and save.';
                            f = warndlg(strcat(s1,s2,s3));
                            StopButtonPushed(app);
                        elseif data(trl,2) == 0 && data(trl,4) == 0 && Par.TimeoutDur_FA > 0 % if FA, then timeout punishment i.e. step=-999; it doesn't matter if we add it before or after ITI since it's just one chunk of time
                            t_punBegin = tic;
                            step = -999;
                            disp('FA timeout');
                        else
                           step = 0;
%                            t_lick = 0;
%                            t0 = tic;
                        end 
                    end
                    
                    if step == 2.1 && toc(t_sti) > iti
                        step = 0;
                    end
                    
                    if step == -999 && toc(t_punBegin) > Par.TimeoutDur_FA %add timeout punishment if FA
                        step = 0;
%                         t_lick = 0;
%                         t0 = tic;
                    end
                    
                    if step == 777 % if LDS, as detected in showDG fx, then we abort and timeout
                        if toc(t_punBegin) > Par.TimeoutDur_NLDS % this t_punBegin was defined in the showDG fx
                            if Par.Passive == 'Y' % in case this is a passive with TS session
                                step = -2.1;
                                show_gray(app);
                                tm1 = tic;
                            else                  % normal session
                                step = 2.1;
                                show_gray(app);
                                t_sti = tic;
%                               t_lick = 0;
%                               t0 = tic;
                            end

                        end
                    end
                    
                    % =======================================
                    
                    % =======================================                  
                    % Block: Performance data storage
                    
                    app.Text_NumAbt.Value = num2str(n_aborted);
                    
                    if perfdata.num_trl + 1 == trl % only run the update if there's a new trl
                        perfdata.num_trl = trl; 
                        trl4cal = trl - 1; % all cal and disp below are backtracked in 1 trial!
                        if perfdata.num_trl > 1
                            perfdata.num_pas_c = sum(data(1:trl4cal,2)==101);
                            perfdata.num_sti_c = sum(data(1:trl4cal,2)==1);
                            perfdata.num_nosti_c = sum(data(1:trl4cal,2)==0);
                            perfdata.num_hit_c = sum(data(1:trl4cal,2)==1 & data(1:trl4cal,4)==1);
                            perfdata.num_miss_c = sum(data(1:trl4cal,2)==1 & data(1:trl4cal,4)==0);
                            perfdata.num_fa_c = sum(data(1:trl4cal,2)==0 & data(1:trl4cal,4)==0);
                            perfdata.num_cr_c = sum(data(1:trl4cal,2)==0 & data(1:trl4cal,4)==1);
                            perfdata.num_sti_nosti_c = perfdata.num_sti_c + perfdata.num_nosti_c;
                            % Display the numbers in the GUI fields
                            app.Text_NumTrl.Value = num2str(trl4cal);
                            app.Text_NumPas.Value = num2str(perfdata.num_pas_c);
                            app.Text_NumHit_c.Value = num2str(perfdata.num_hit_c);
                            app.Text_NumMiss_c.Value = num2str(perfdata.num_miss_c);
                            app.Text_NumCR_c.Value = num2str(perfdata.num_cr_c);
                            app.Text_NumFA_c.Value = num2str(perfdata.num_fa_c);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,1) = trl4cal;
                            perfdata.log(trl4cal,2) = perfdata.num_pas_c;
                            perfdata.log(trl4cal,3) = perfdata.num_hit_c;
                            perfdata.log(trl4cal,6) = perfdata.num_fa_c;
                            perfdata.log(trl4cal,4) = perfdata.num_miss_c;
                            perfdata.log(trl4cal,5) = perfdata.num_cr_c;
                            perfdata.log(trl4cal,17) = n_aborted;
                        end 
                        
                        if perfdata.num_sti_nosti_c > 9
                            perfdata.num_hit_rate_c = perfdata.num_hit_c / perfdata.num_sti_c;
                                if isnan(perfdata.num_hit_rate_c) || perfdata.num_hit_rate_c == 0
                                    perfdata.num_hit_rate_c = 0.01;
                                elseif perfdata.num_hit_rate_c == 1
                                    perfdata.num_hit_rate_c = 0.99;
                                end
                            perfdata.num_fa_rate_c = perfdata.num_fa_c / perfdata.num_nosti_c;
                                if isnan(perfdata.num_fa_rate_c) || perfdata.num_fa_rate_c == 0
                                    perfdata.num_fa_rate_c = 0.01;
                                elseif perfdata.num_fa_rate_c == 1
                                    perfdata.num_fa_rate_c = 0.99;
                                end
                            perfdata.dprime_c = norminv(perfdata.num_hit_rate_c) - norminv(perfdata.num_fa_rate_c);
                            %Display
                            app.Text_NumHR_c.Value = num2str(perfdata.num_hit_rate_c);
                            app.Text_NumFAR_c.Value = num2str(perfdata.num_fa_rate_c);
                            app.Text_NumDprime_c.Value = num2str(perfdata.dprime_c);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,7) = perfdata.num_hit_rate_c;
                            perfdata.log(trl4cal,8) = perfdata.num_fa_rate_c;
                            perfdata.log(trl4cal,9) = perfdata.dprime_c;
                            
                        end
                        
                        if  perfdata.num_sti_nosti_c > 19
                            last20_pas = sum(data(trl4cal-19:trl4cal,2)==101);
                            if last20_pas > 0
                                correction = last20_pas;                                
                            else
                                correction = 0;
                            end
                            last20_sti = sum(data(trl4cal-(19+correction):trl4cal,2)==1);
                            last20_nosti = sum(data(trl4cal-(19+correction):trl4cal,2)==0);
                            last20_hit = sum(data(trl4cal-(19+correction):trl4cal,2)==1 & data(trl4cal-(19+correction):trl4cal,4)==1);
                            last20_fa = sum(data(trl4cal-(19+correction):trl4cal,2)==0 & data(trl4cal-(19+correction):trl4cal,4)==0);
                            perfdata.num_hit_rate_i = last20_hit/last20_sti;
                                if isnan(perfdata.num_hit_rate_i) || perfdata.num_hit_rate_i == 0 
                                    perfdata.num_hit_rate_i = 0.01;
                                elseif perfdata.num_hit_rate_i == 1
                                    perfdata.num_hit_rate_i = 0.99;
                                end                            
                            perfdata.num_fa_rate_i = last20_fa/last20_nosti;
                                if isnan(perfdata.num_fa_rate_i) || perfdata.num_fa_rate_i == 0
                                    perfdata.num_fa_rate_i = 0.01;
                                elseif perfdata.num_fa_rate_i == 1
                                    perfdata.num_fa_rate_i = 0.99;
                                end
                            perfdata.dprime_i = norminv(perfdata.num_hit_rate_i)-norminv(perfdata.num_fa_rate_i); 
                            % Display
                            app.Text_NumHit_i.Value = num2str(last20_hit);
                            app.Text_NumMiss_i.Value= num2str(last20_sti-last20_hit);
                            app.Text_NumFA_i.Value = num2str(last20_fa);
                            app.Text_NumCR_i.Value = num2str(last20_nosti-last20_fa);
                            app.Text_NumHR_i.Value = num2str(perfdata.num_hit_rate_i);
                            app.Text_NumFAR_i.Value = num2str(perfdata.num_fa_rate_i);
                            app.Text_NumDprime_i.Value = num2str(perfdata.dprime_i);
                            % Update d-prime line in Figure 1
                            addpoints(fig1, trl4cal, perfdata.dprime_i);
                            % Save the numbers in perfdata.log
                            perfdata.log(trl4cal,10) = last20_hit;
                            perfdata.log(trl4cal,11) = last20_sti - last20_hit;
                            perfdata.log(trl4cal,12) = last20_nosti - last20_fa;
                            perfdata.log(trl4cal,13) = last20_fa;
                            perfdata.log(trl4cal,14) = perfdata.num_hit_rate_i;
                            perfdata.log(trl4cal,15) = perfdata.num_fa_rate_i;
                            perfdata.log(trl4cal,16) = perfdata.dprime_i;
                            
                        end
                    end
                    % =======================================
                    
                    
                    drawnow limitrate; %this must run every loop otherwise it lags!
                    xt8 = toc(m1);
                    time = [time; xt8];
                    
                    if xt8 > 0.05
                        time_long = [time_long; xt8 toc(t_abs0) step];
                    end
            

                end


        end

        % Button pushed function: InterjectButton
        function InterjectButtonPushed(app, event)
            global passive
            passive = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global Par
            global data
            global perfdata
            global time time_long
            global trl
            global fig1
            global t_trl_abs t_lick_abs t_abs0 rt_aborted n_aborted
            
            % Stop trial run
            Par.run = 0;            
            pause(1); %extra scanning time before ending session
            if Par.g2p == 1
                fprintf(Par.Lcon,'S'); %stop imaging
                fclose(Par.Lcon);
            end
            %%

                       
            % Prepare table for log file
            data_table = array2table(data(1:trl-1,:));
            data_table.Properties.VariableNames = {'DateTime', 'TrlType', 'NLDS', 'Outcome', 'RT'};
            perfdata.log = array2table(perfdata.log(1:trl-1,:));
            perfdata.log.Properties.VariableNames = {'TrialN','incPassive','Hit_c','Miss_c','CR_c','FA_c','HR_c','FAR_c','Dp_c','Hit_i','Miss_i','CR_i','FA_i','HR_i','FAR_i','DP_i','n_aborted'};
            %%
            
            % File name
            FN = char(strcat(app.SubjectTextArea.Value, '_', app.DateTextArea.Value, '_', num2str(app.SessionEditField.Value)));
            %%
            
            %JSON (path is par.savepath)
            json.version = '1.0';
            json.project = Par.dbfields.project;
            json.dataset = Par.dbfields.dataset;  
            json.date = Par.dbfields.date;
            json.subject = Par.dbfields.subject;
            json.investigator = Par.dbfields.investigator;
            json.setup = Par.dbfields.setup;
            json.condition = Par.dbfields.condition;   %not essential
            json.stimulus = Par.dbfields.stimulus; %name (str) of function
            json.logfile = [FN '_log']; %name for your logfile
            json.session = app.SessionEditField.Value;
            % json.script = thisRecord.script;
            json.display = Par.PTB;
            savejson('', json, [FN '_session.json']);
            %%
            
            %Convert t_trl_abs and t_lick_abs to s
            t_trl_abs (2,:) = t_trl_abs (2,:) ./ 1000;
            t_lick_abs = t_lick_abs ./ 1000;
            
            % Save log (path is par.savepath)
            uisave({'data_table','perfdata', 'time', 'Par', 't_trl_abs', 't_lick_abs', 't_abs0', 'time_long', 'rt_aborted','n_aborted'}, [FN '_log']);
            waitfor(msgbox('Log saved!'));
            %%
            
            % Close the sound port
%             try
%                 PsychPortAudio('Close',sound.pahandle);
%             catch ME
%                 disp('Audio port closed by itself due to inactivity.')
%             end
            
            %%

            
        end

        % Button pushed function: GetBLButton
        function GetBLButtonPushed(app, event)
         global Par
            fprintf(Par.comm_Lick, 'IB'); % Get current BL level
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
         global Par
         fprintf(Par.comm_Lick, strcat('IH',num2str(app.ThInput.Value))); %Set threshold
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
          global Par
          fprintf(Par.comm_Lick, strcat('IL',num2str(app.RewardEditField.Value*1000))); % Set left valve reward time
        end

        % Button pushed function: SetButton_3
        function SetButton_3Pushed(app, event)
            global Par
            global texDG dstRect sound
            % global sound
            
            % Set the parameters in Par
            Par.StimDur = app.StimdurationEditField.Value;
            Par.RespWin = app.RespwinEditField.Value;
            Par.RewardDur = app.RewardEditField.Value;
            Par.ITI1 = app.ITI1.Value;
            Par.ITI2 = app.ITI2.Value;
            Par.Passive = app.PassivewTSDropDown.Value;
            Par.NLDS = app.NLDSDropDown.Value;
                if Par.NLDS == 'Y'
                    Par.NLDS = 1;
                else
                    Par.NLDS = 0;
                end
            Par.OriGo = app.GoEditField.Value;
            Par.OriNogo = app.NogoEditField.Value;
            Par.CPD = app.CPDEditField.Value;
            Par.CPS = app.CPSEditField.Value;
            Par.ApSize = app.ApSizeEditField.Value;
            Par.ApLocX = app.ApLocXEditField.Value;
            Par.ApLocY = app.ApLocYEditField.Value;
            Par.DegToPx = app.DegToPxEditField.Value;
            
            Par.TimeoutDur_NLDS = app.NLDStimeoutdurEditField.Value;
            Par.EndRelToOnset_NLDS = app.NLDSendrelonEditField.Value;
            if Par.EndRelToOnset_NLDS > Par.StimDur
                error('NLDS end relative to stim onset is outside of stim duration window!');
            end
            Par.TimeoutDur_FA = app.FAtimeoutdurEditField.Value;
            
            % Build DG texture
            support = 2000; % 2000px
            ApSizePx = Par.ApSize * Par.DegToPx;
            texDG = CreateProceduralSquareWaveGrating(Par.PTB.window, support, support, [.5 .5 .5 0], ApSizePx/2);
            
            % Build spatial offset dstRect for the Ap
            xvals = linspace(-0.8667*Par.PTB.screenXpixels/2,0.8667*Par.PTB.screenXpixels/2,16); % based on quickRFmap
            yvals = linspace(-0.8182*Par.PTB.screenYpixels/2,0.8182*Par.PTB.screenYpixels/2,12); % based on quickRFmap
            xc = xvals(Par.ApLocX)+Par.PTB.screenXpixels/2;
            yc = yvals(Par.ApLocY)+Par.PTB.screenYpixels/2;
            dstRect = CenterRectOnPoint(Screen('Rect', texDG), xc, yc);
            
            % Initialize the lick sound
            % Version: sine at particular freq with noise
            if app.LicksoundDropDown.Value == 'Y' && Par.lickSound == 0
                Par.lickSound = 1;
                sound.len = 0.05;                        % Length (sec)
                sound.f   =3000;                                     % Frequency (2 kHz)
                sound.Fs  = 44100;                                     % Sampling Frequency (Hz)
                sound.t   = linspace(0, sound.len, sound.Fs*sound.len);                 % Time Vector
                sound.signal = sin(2*pi*sound.f*sound.t);                         % Signal (10 kHz sine)
                sound.noise = sound.signal + 0.1*randn(size(sound.signal));       % Add noise
                sound.noise = [sound.noise; sound.noise];              % Make the sound 2-ch
                sound.nrchannels = 2;
                sound.device = [];
                sound.repetitions = 1;
                InitializePsychSound(1);
                sound.pahandle = PsychPortAudio('Open', sound.device, [], 2, sound.Fs, sound.nrchannels);
                PsychPortAudio('FillBuffer', sound.pahandle, sound.noise);
            elseif app.LicksoundDropDown.Value == 'Y' && Par.lickSound == 1
                disp('Sound already initialized.')
            else
                Par.lickSound = 0;
            end
            
            % Version: white noise
%             sound.len = Par.NoiseDur_NLDS;                         % Length (sec)
%             sound.Fs  = 44100;                                     % Sampling Frequency (Hz)
%             sound.SigLen   = sound.Fs*sound.len;                   % Signal length
%             sound.noise = wgn(sound.SigLen, 1, 0);                 % White-noise generation
%             sound.noise = [sound.noise'; sound.noise'];            % Make the sound 2-ch
%             sound.nrchannels = 2;
%             sound.device = [];
%             sound.repetitions = 1;
%             InitializePsychSound(1);
%             sound.pahandle = PsychPortAudio('Open', sound.device, [], 2, sound.Fs, sound.nrchannels);
%             PsychPortAudio('FillBuffer', sound.pahandle, sound.noise);  

            % Final check
            if Par.g2p == 1 && Par.lick == 1 && Par.logsave == 1
                Par.set = 1;
                app.ReadyLamp.Color = [0 1 0];
            elseif Par.g2p == 0 && Par.lick == 1 && Par.logsave == 1
                app.ReadyLamp.Color = [0.49 0.18 0.56];
                Par.set = 1;
                waitfor(msgbox('Trial run without 2p!'));
            else
                app.ReadyLamp.Color = [1 0 0];
                Par.set = 0;
                waitfor(msgbox('NOT ready! Check lick sensor, S2P, or log path!'));
                error('NOT ready! Check lick sensor, S2P, or log path!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TrialstructurePanel = uipanel(app.UIFigure);
            app.TrialstructurePanel.Title = 'Trial structure';
            app.sLabel = uilabel(app.TrialstructurePanel);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.TrialstructurePanel);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.TrialstructurePanel);
            app.sLabel_3.Text = 's';
            app.sLabel_4 = uilabel(app.TrialstructurePanel);
            app.sLabel_4.Text = 's';
            app.PassivewTSDropDownLabel = uilabel(app.TrialstructurePanel);
            app.PassivewTSDropDownLabel.Text = 'Passive w/ TS?';
            app.PassivewTSDropDown = uidropdown(app.TrialstructurePanel);
            app.PassivewTSDropDown.Items = {'N', 'Y'};
            app.PassivewTSDropDown.Value = 'N';
            app.RewardEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.RewardEditFieldLabel.Text = 'Reward';
            app.RewardEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.RewardEditField.Value = 0.25;
            app.StimdurationEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.StimdurationEditFieldLabel.Text = 'Stim duration';
            app.StimdurationEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.StimdurationEditField.Value = 2;
            app.RespwinEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.RespwinEditFieldLabel.Text = 'Resp win';
            app.RespwinEditField = uieditfield(app.TrialstructurePanel, 'numeric');
            app.RespwinEditField.Value = 1;
            app.ITIEditFieldLabel = uilabel(app.TrialstructurePanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITI1 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI1.Value = 5;
            app.toLabel = uilabel(app.TrialstructurePanel);
            app.toLabel.Text = 'to';
            app.ITI2 = uieditfield(app.TrialstructurePanel, 'numeric');
            app.ITI2.Value = 7;
            app.SetButton = uibutton(app.TrialstructurePanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.NLDSDropDownLabel = uilabel(app.TrialstructurePanel);
            app.NLDSDropDownLabel.Text = 'NLDS?';
            app.NLDSDropDown = uidropdown(app.TrialstructurePanel);
            app.NLDSDropDown.Items = {'N', 'Y'};
            app.NLDSDropDown.Value = 'N';
            app.CommunicationPanel = uipanel(app.UIFigure);
            app.CommunicationPanel.Title = 'Communication';
            app.comm_Lick_lamp = uilamp(app.CommunicationPanel);
            app.comm_Lick_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Lick_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Lick_connectButtonPushed, true);
            app.comm_Lick_connect.Text = 'Connect';
            app.LickEditFieldLabel = uilabel(app.CommunicationPanel);
            app.LickEditFieldLabel.Text = 'Lick';
            app.comm_Lick_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Lick_com.Value = 'com17';
            app.comm_2pPC_lamp = uilamp(app.CommunicationPanel);
            app.comm_2pPC_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_2pPC_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_2pPC_connectButtonPushed, true);
            app.comm_2pPC_connect.Text = 'Connect';
            app.pPCEditFieldLabel = uilabel(app.CommunicationPanel);
            app.pPCEditFieldLabel.Text = '2p PC';
            app.comm_2pPC_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_2pPC_com.Value = 'G2P';
            app.comm_Motion_lamp = uilamp(app.CommunicationPanel);
            app.comm_Motion_connect = uibutton(app.CommunicationPanel, 'push');
            app.comm_Motion_connect.ButtonPushedFcn = createCallbackFcn(app, @comm_Motion_connectPushed, true);
            app.comm_Motion_connect.Enable = 'off';
            app.comm_Motion_connect.Text = 'Connect';
            app.MotionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.MotionEditFieldLabel.Text = 'Motion';
            app.comm_Motion_com = uieditfield(app.CommunicationPanel, 'text');
            app.comm_Motion_com.Value = 'com17';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.SubjectTextAreaLabel = uilabel(app.LogPanel);
            app.SubjectTextAreaLabel.Text = 'Subject';
            app.SubjectTextArea = uitextarea(app.LogPanel);
            app.SubjectTextArea.Editable = 'off';
            app.DateTextAreaLabel = uilabel(app.LogPanel);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.LogPanel);
            app.DateTextArea.Editable = 'off';
            app.SavepathTextAreaLabel = uilabel(app.LogPanel);
            app.SavepathTextAreaLabel.Text = 'Save path';
            app.SavepathTextArea = uitextarea(app.LogPanel);
            app.SavepathTextArea.Editable = 'off';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SessionEditFieldLabel = uilabel(app.LogPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LogPanel, 'numeric');
            app.SessionEditField.Value = 1;
            app.VisualPanel = uipanel(app.UIFigure);
            app.VisualPanel.Title = 'Visual';
            app.GoEditFieldLabel = uilabel(app.VisualPanel);
            app.GoEditFieldLabel.Text = 'Go';
            app.GoEditField = uieditfield(app.VisualPanel, 'numeric');
            app.degLabel = uilabel(app.VisualPanel);
            app.degLabel.Text = 'deg';
            app.degLabel_2 = uilabel(app.VisualPanel);
            app.degLabel_2.Text = 'deg';
            app.NogoEditFieldLabel = uilabel(app.VisualPanel);
            app.NogoEditFieldLabel.Text = 'Nogo';
            app.NogoEditField = uieditfield(app.VisualPanel, 'numeric');
            app.NogoEditField.Value = 90;
            app.CPDEditFieldLabel = uilabel(app.VisualPanel);
            app.CPDEditFieldLabel.Text = 'CPD';
            app.CPDEditField = uieditfield(app.VisualPanel, 'numeric');
            app.CPDEditField.Value = 0.05;
            app.CPSEditFieldLabel = uilabel(app.VisualPanel);
            app.CPSEditFieldLabel.Text = 'CPS';
            app.CPSEditField = uieditfield(app.VisualPanel, 'numeric');
            app.CPSEditField.Value = 2;
            app.ApSizeEditFieldLabel = uilabel(app.VisualPanel);
            app.ApSizeEditFieldLabel.Text = 'ApSize';
            app.ApSizeEditField = uieditfield(app.VisualPanel, 'numeric');
            app.ApSizeEditField.Value = 50;
            app.ApLocXEditFieldLabel = uilabel(app.VisualPanel);
            app.ApLocXEditFieldLabel.Text = 'ApLocX';
            app.ApLocXEditField = uieditfield(app.VisualPanel, 'numeric');
            app.ApLocXEditField.Value = 8;
            app.DegToPxEditFieldLabel = uilabel(app.VisualPanel);
            app.DegToPxEditFieldLabel.Text = 'DegToPx';
            app.DegToPxEditField = uieditfield(app.VisualPanel, 'numeric');
            app.DegToPxEditField.Value = 8.34;
            app.ApLocYEditFieldLabel = uilabel(app.VisualPanel);
            app.ApLocYEditFieldLabel.Text = 'ApLocY';
            app.ApLocYEditField = uieditfield(app.VisualPanel, 'numeric');
            app.ApLocYEditField.Value = 6;
            app.degLabel_3 = uilabel(app.VisualPanel);
            app.degLabel_3.Text = 'deg';
            app.ExecutioncontrolPanel = uipanel(app.UIFigure);
            app.ExecutioncontrolPanel.Title = 'Execution control';
            app.StartButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NumberoftrialsLabel = uilabel(app.ExecutioncontrolPanel);
            app.NumberoftrialsLabel.Text = 'Number of trials:';
            app.StopButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Text_NumTrl = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumTrl.Editable = 'off';
            app.CurrentBLEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.CurrentBLEditField.Editable = 'off';
            app.GetBLButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.GetBLButton.ButtonPushedFcn = createCallbackFcn(app, @GetBLButtonPushed, true);
            app.GetBLButton.Text = 'Get BL';
            app.SetButton_2 = uibutton(app.ExecutioncontrolPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.ThInput = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.ThInput.Value = 1500;
            app.NolickEditFieldLabel_2 = uilabel(app.ExecutioncontrolPanel);
            app.NolickEditFieldLabel_2.Text = 'thres. Δ';
            app.Trialcounterupdatedelay1trialLabel = uilabel(app.ExecutioncontrolPanel);
            app.Trialcounterupdatedelay1trialLabel.Text = 'Trial counter: (update delay: 1 trial)';
            app.InterjectButton = uibutton(app.ExecutioncontrolPanel, 'push');
            app.InterjectButton.ButtonPushedFcn = createCallbackFcn(app, @InterjectButtonPushed, true);
            app.InterjectButton.Text = 'Interject';
            app.InterjectofpassivesEditFieldLabel = uilabel(app.ExecutioncontrolPanel);
            app.InterjectofpassivesEditFieldLabel.Text = 'Interject # of passives';
            app.InterjectofpassivesEditField = uieditfield(app.ExecutioncontrolPanel, 'numeric');
            app.InterjectofpassivesEditField.Value = 1;
            app.LickLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.LickLampLabel.Text = 'Lick';
            app.LickLamp = uilamp(app.ExecutioncontrolPanel);
            app.MotionLampLabel = uilabel(app.ExecutioncontrolPanel);
            app.MotionLampLabel.Text = 'Motion';
            app.MotionLamp = uilamp(app.ExecutioncontrolPanel);
            app.incpasLabel = uilabel(app.ExecutioncontrolPanel);
            app.incpasLabel.Text = 'inc. pas:';
            app.Text_NumPas = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumPas.Editable = 'off';
            app.excabtLabel = uilabel(app.ExecutioncontrolPanel);
            app.excabtLabel.Text = 'exc. abt:';
            app.Text_NumAbt = uitextarea(app.ExecutioncontrolPanel);
            app.Text_NumAbt.Editable = 'off';
            app.Performanceinstantaneouspast20trialsPanel = uipanel(app.UIFigure);
            app.Performanceinstantaneouspast20trialsPanel.Title = 'Performance - instantaneous (past 20 trials)';
            app.HLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.HLabel_2.Text = 'H';
            app.Text_NumHit_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.CRLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.CRLabel_2.Text = 'CR';
            app.Text_NumCR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.MLabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.MLabel_2.Text = 'M';
            app.Text_NumMiss_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.FALabel_2 = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.FALabel_2.Text = 'FA';
            app.Text_NumFA_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.HRLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.HRLabel.Text = 'HR';
            app.Text_NumHR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.FARLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.FARLabel.Text = 'FAR';
            app.Text_NumFAR_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.idLabel = uilabel(app.Performanceinstantaneouspast20trialsPanel);
            app.idLabel.Text = 'id''';
            app.Text_NumDprime_i = uitextarea(app.Performanceinstantaneouspast20trialsPanel);
            app.PerformancecumulativePanel = uipanel(app.UIFigure);
            app.PerformancecumulativePanel.Title = 'Performance - cumulative';
            app.HLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.HLabel_3.Text = 'H';
            app.Text_NumHit_c = uitextarea(app.PerformancecumulativePanel);
            app.cdLabel = uilabel(app.PerformancecumulativePanel);
            app.cdLabel.Text = 'cd''';
            app.Text_NumDprime_c = uitextarea(app.PerformancecumulativePanel);
            app.HRLabel_2 = uilabel(app.PerformancecumulativePanel);
            app.HRLabel_2.Text = 'HR';
            app.Text_NumHR_c = uitextarea(app.PerformancecumulativePanel);
            app.FARLabel_2 = uilabel(app.PerformancecumulativePanel);
            app.FARLabel_2.Text = 'FAR';
            app.Text_NumFAR_c = uitextarea(app.PerformancecumulativePanel);
            app.CRLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.CRLabel_3.Text = 'CR';
            app.Text_NumCR_c = uitextarea(app.PerformancecumulativePanel);
            app.MLabel_3 = uilabel(app.PerformancecumulativePanel);
            app.MLabel_3.Text = 'M';
            app.Text_NumMiss_c = uitextarea(app.PerformancecumulativePanel);
            app.FALabel_3 = uilabel(app.PerformancecumulativePanel);
            app.FALabel_3.Text = 'FA';
            app.Text_NumFA_c = uitextarea(app.PerformancecumulativePanel);
            app.SetButton_3 = uibutton(app.UIFigure, 'push');
            app.SetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SetButton_3Pushed, true);
            app.SetButton_3.Text = 'Set';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready?';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.NLDSFAPanel = uipanel(app.UIFigure);
            app.NLDSFAPanel.Title = 'NLDS & FA';
            app.NLDSendrelonEditFieldLabel = uilabel(app.NLDSFAPanel);
            app.NLDSendrelonEditFieldLabel.Text = 'NLDS end rel. on';
            app.NLDSendrelonEditField = uieditfield(app.NLDSFAPanel, 'numeric');
            app.NLDSendrelonEditField.Value = 1;
            app.sLabel_5 = uilabel(app.NLDSFAPanel);
            app.sLabel_5.Text = 's';
            app.FAtimeoutdurEditFieldLabel = uilabel(app.NLDSFAPanel);
            app.FAtimeoutdurEditFieldLabel.Text = 'FA timeout dur';
            app.FAtimeoutdurEditField = uieditfield(app.NLDSFAPanel, 'numeric');
            app.sLabel_6 = uilabel(app.NLDSFAPanel);
            app.sLabel_6.Text = 's';
            app.NLDStimeoutdurEditFieldLabel = uilabel(app.NLDSFAPanel);
            app.NLDStimeoutdurEditFieldLabel.Text = 'NLDS timeout dur';
            app.NLDStimeoutdurEditField = uieditfield(app.NLDSFAPanel, 'numeric');
            app.NLDStimeoutdurEditField.Value = 5;
            app.sLabel_7 = uilabel(app.NLDSFAPanel);
            app.sLabel_7.Text = 's';
            app.LicksoundDropDownLabel = uilabel(app.UIFigure);
            app.LicksoundDropDownLabel.Text = 'Lick sound';
            app.LicksoundDropDown = uidropdown(app.UIFigure);
            app.LicksoundDropDown.Items = {'Y', 'N'};
            app.LicksoundDropDown.Value = 'N';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Trial number')
            ylabel(app.UIAxes, 'D-prime (ins.)')
            app.UIAxes.XLim = [0 300];
            app.UIAxes.YLim = [-1 3];
            app.UIAxes.YTick = [-1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.UIAxes.YTickLabel = {'-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ACh_training_v4b2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end